classdef attitude_sim_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        attitudeWindowUIFigure         matlab.ui.Figure
        AttitudeSimulatorLabel         matlab.ui.control.Label
        GoBack                         matlab.ui.control.Image
        SimulationsetupPanel           matlab.ui.container.Panel
        ManeuverDropDownLabel          matlab.ui.control.Label
        ManeuverDropDown               matlab.ui.control.DropDown
        MagnetometerCheckBox           matlab.ui.control.CheckBox
        IMUgyroCheckBox                matlab.ui.control.CheckBox
        SunsensorCheckBox              matlab.ui.control.CheckBox
        ReactionwheelCheckBox          matlab.ui.control.CheckBox
        MagnetorquerCheckBox           matlab.ui.control.CheckBox
        SamplingtimesecEditFieldLabel  matlab.ui.control.Label
        SamplingtimesecEditField       matlab.ui.control.NumericEditField
        NumberoforbitsEditFieldLabel   matlab.ui.control.Label
        NumberoforbitsEditField        matlab.ui.control.NumericEditField
        AnimationPanel                 matlab.ui.container.Panel
        AnimationAxes                  matlab.ui.control.UIAxes
        PlotsPanel                     matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        Plot1                          matlab.ui.container.Tab
        UIAxes1                        matlab.ui.control.UIAxes
        Plot4                          matlab.ui.container.Tab
        UIAxes4                        matlab.ui.control.UIAxes
        Plot2                          matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        Plot3                          matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
        ControllerPanel                matlab.ui.container.Panel
        Slider1                        matlab.ui.control.Slider
        Slider2                        matlab.ui.control.Slider
        Slider3                        matlab.ui.control.Slider
        GainLabel1                     matlab.ui.control.Label
        GainLabel2                     matlab.ui.control.Label
        GainLabel3                     matlab.ui.control.Label
        xdegsSpinnerLabel              matlab.ui.control.Label
        xdegsSpinner                   matlab.ui.control.Spinner
        ydegsSpinnerLabel              matlab.ui.control.Label
        ydegsSpinner                   matlab.ui.control.Spinner
        zdegsSpinnerLabel              matlab.ui.control.Label
        zdegsSpinner                   matlab.ui.control.Spinner
        StartButton                    matlab.ui.control.StateButton
        PauseButton                    matlab.ui.control.StateButton
        StopButton                     matlab.ui.control.StateButton
        simProgress                    matlab.ui.control.Label
    end

    
    methods (Access = private)
                
        function constants(app)
            
            global G mu r_equator r_polar w_earth J2_earth
            global COMP_SCRN_SIZE deg2rad rad2deg

            G = 6.67e-11;           %% Gravitational constant
            r_equator = 6.378e6;    %% Earth radius (equator) [m]
            r_polar = 6.356e6;      %% Earth radius (polar) [m]
            w_earth = 4.178e-3;     %% Angular velocity [deg/s]
            mu = 3.986e14;          %% [m^3/s^2]
            J2_earth = 1.08263e-3;
            
            deg2rad = pi/180;
            rad2deg = 180/pi;
            COMP_SCRN_SIZE = get(0, 'ScreenSize');
            
        end
        
        function magnetorquerParameters(app)
            
            global nCoils maxCurr secArea
            
            %% Define magnetorquer characteristics
            nCoils = [12000; 12000; 320];
            maxCurr = [0.2143; 0.2143; 0.2135]; %% [A]
            secArea = [2.2698E-4; 2.2698E-4; 8.7695E-3];
            
        end
        
        function magnetometerParameters(app)
            
            global lpFilter_mag sampleR_mag constBias_mag maxNoise_mag
            
            %% HMR2300
            lpFilter_mag = 0.4;                          %% Low pass filter gain
            %sampleR_mag = 10;                            %% Sample rate [Hz] (selectable 10-154)
            sampleR_mag = 2;
            constBias_mag = [0; 0; 0];                   %% Bias [T] 
            maxNoise_mag = 1e-6;                         %% Maximum amplitude of noise [T]
        
        end
        
        function gyroParameters(app)
            
            global lpFilter_gyro sampleR_gyro constBias_gyro maxNoise_gyro
            
            %% ADIS16445 
            lpFilter_gyro = 0.45;           %% Low pass filter gain 
            %sampleR_gyro = 819;             %% Sample rate [Hz] 
            sampleR_gyro = 50;
            constBias_gyro = [0; 0; 0];     %% Bias [deg/s] 
            %maxNoise_gyro = 0.22;           %% Maximum amplitude of noise [deg/s]
            maxNoise_gyro = 0.2;
            
        end
        
        function satelliteParameters(app)
            
            global Sm iner invin dim
            
            Sm = 2.6;                            %% SSS-1's mass [kg]
            dim = [0.1, 0.1, 0.2];             %% SSS-1's dimensions [m]
            iner = [0.0108, 0,      0;
                    0,      0.0108, 0;
                    0,      0,      0.0043];
%             iner = [1.1470 -0.0010  0.0010;     %% SSS-1's Moment of inertia (before deployment)
%                     -0.0010  1.1360 -0.0070;
%                      0.0010 -0.0070  0.5330];
%             iner = [7.6590 -0.0020  0.0380;    %% SSS-1's Moment of inertia (after deployment)
%                     -0.0020  7.6490 -0.0170; 
%                      0.0380 -0.0170  0.5330];
            invin = inv(iner);
                             
        end
        
        function orbitParameters(app)
            
            global a e i W w M nu To kepel stat delk altitude mjd dfra raan_dot arg_dot
            global mu J2_earth r_equator
                          
            %%% Get keplerian elements from TLE
            tle = read_tle('SSS-1_TLE.txt');
            [kepel,mjd,dfra] = tle_kepel(tle);
            a = kepel(1,1);             %% Semi-major axis (m)
            e = 0;              %% Eccentricity
            kepel(1,2) = 0;
            i = rad2deg(kepel(1,3));             %% Inclination (rad)
            W = rad2deg(kepel(1,4));             %% Right ascension of ascending node (rad)
            w = rad2deg(kepel(1,5));             %% Argument of perigee (rad)
            M = 0;                               %% Mean anomaly (rad)
            kepel(1,6) = 0;
            %nu = nuFromM(M, e, 1e-8);   %% True anomaly (rad)
            nu = 0;
            
            %%% Determine the variation in the keplerian elements (due to Earth's oblateness)
            delk = delkep(kepel);      
            
            %%% Determine the orbital state vector
%              raan_dot = rad2deg(delk(4)); 
%              arg_dot = rad2deg(delk(5));
            raan_dot = rad2deg((-3 / 2) * cosd(i) * J2_earth * r_equator^2 * sqrt(mu) / a^(7 / 2));
            arg_dot = rad2deg((-3 / 2) * ((-5 / 2) * sind(i)^2 - 2) * J2_earth * r_equator^2 * sqrt(mu) / a^(7 / 2));
            
            prfclPostn_spcrft = [a; 1; 0];
            rotMatrx_orbt = rotMatrx313BdyToInrtl(W, i, w);
            stat(1:3) = rotMatrx_orbt * prfclPostn_spcrft;
            %stat = kepel_statvec(kepel);    %% m and m/s
                      
            To = 2*pi*sqrt(a^3/mu);     %% Period of the orbit
            altitude = 5e5;             %% [m]
                                
        end
                        
        function attitudeParameters(app)
            
            global w0 euler0 yaw0 quat
            global pitch0 roll0 rot_mat_zyx
           
            %global deg2rad rad2deg
                                 
                   
            %% Initial angular velocity in body frame
            %w0 = [1.5; 1.5; 1.5];           %% [rad/s]
            %w0 = deg2rad([0.5; 1; 2]);                %% [deg/s]
            %w0 = deg2rad([3; 3; 3]);
            w0 = deg2rad([app.xdegsSpinner.Value; app.ydegsSpinner.Value; app.zdegsSpinner.Value]);
            
            %% Attitude in euler angles (zyx) - from SSS-1 ADCS
            %euler0 = [3.0, 27.0, 2.5]'*deg2rad;
            %euler0 = [0, 0, 0];
            %rot_mat_zyx = ezyxrmx(euler0);
            
            %% Attitude in quaternions
            
            %quat = ezyxquat(euler0);                           %% Scalar last
            %quat = [quat(4); quat(1); quat(2); quat(3)];    %% Scalar first
            
            yaw0 = 0;    %% [deg]
            pitch0 = 0;  %% [deg]
            roll0 = 0;   %% [deg]
            
            quat(1, 1) = cosd(roll0 / 2) * cosd(pitch0 / 2) * cosd(yaw0 / 2) + sind(roll0 / 2) * sind(pitch0 / 2) * sind(yaw0 / 2);
            quat(2, 1) = sind(roll0 / 2) * cosd(pitch0 / 2) * cosd(yaw0 / 2) - cosd(roll0 / 2) * sind(pitch0 / 2) * sind(yaw0 / 2);
            quat(3, 1) = cosd(roll0 / 2) * sind(pitch0 / 2) * cosd(yaw0 / 2) + sind(roll0 / 2) * cosd(pitch0 / 2) * sind(yaw0 / 2);
            quat(4, 1) = cosd(roll0 / 2) * cosd(pitch0 / 2) * sind(yaw0 / 2) - sind(roll0 / 2) * sind(pitch0 / 2) * cosd(yaw0 / 2); 
            
        end
        
        function setupSatellitePlot(app)
            
            global dim quat w0 iner magField_i viewAzmth viewElvtn
            
            global w_body w_desired w_body_dot
            global quat_dot magField_body satTrnsfrm
            
            %% Angular velocity
            w_body = w0;
            w_desired = [0;0;0];
            
            %% Derivative
            w_body_dot = iner \ -cross(w_body,iner*w_body);
            quat_dot = [0, -w_body(1), -w_body(2), -w_body(3);...
                 w_body(1), 0, w_body(3), -w_body(2); w_body(2),...
                -w_body(3), 0, w_body(1); w_body(3), w_body(2),...
                -w_body(1), 0] * quat * 0.5;
            
            %% Magnetic moment
            magField_body = quatrotate(quat', magField_i')';
            
            %% GUI update: Animation Panel            
            app.AnimationAxes.BackgroundColor = 'k';
            axis(app.AnimationAxes, 'off');
            view(app.AnimationAxes, viewAzmth, viewElvtn);
            axis(app.AnimationAxes, 'vis3d');
            hold(app.AnimationAxes, 'on');
            plot3(app.AnimationAxes,[0,max(dim/2)+0.05],[0,0],[0,0],'Color','w','LineWidth',2,'Clipping','off');
            plot3(app.AnimationAxes,[0,0],[0,max(dim/2)+0.05],[0,0],'Color','w','LineWidth',2,'Clipping','off');
            plot3(app.AnimationAxes,[0,0],[0,0],[0,max(dim/2)+0.05],'Color','w','LineWidth',2,'Clipping','off');
            text(app.AnimationAxes,max(dim/2)+0.1,0,0,'X_I','Color','w');
            text(app.AnimationAxes,0,max(dim/2)+0.1,0,'Y_I','Color','w');
            text(app.AnimationAxes,0,0,max(dim/2)+0.1,'Z_I','Color','w');
            
            satTrnsfrm = hgtransform(app.AnimationAxes);
            hold on;            
            set(spcrft(dim, true), 'Parent', satTrnsfrm);
            
            if (norm(quat(2:4,1)) ~= 0)
                satTrnsfrm.Matrix = makehgtform('axisrotate', quat(2:4, 1), 2*acos(quat(1, 1)));
            end
            
            close
            
            zoom(app.AnimationAxes, 0.5);
            hold(app.AnimationAxes, 'off');
            
        end
        
        function setupGyroPlot(app)
             
            global constBias_gyro maxNoise_gyro w_body
            global w_body_meas_gyro noiseVctr_gyro w_body_raw_gyro prev_w_body_raw_gyro
            global w_body_meas_gyro_pltX w_body_meas_gyro_pltY w_body_meas_gyro_pltZ zero
                        
            constBias_gyro = deg2rad(constBias_gyro);
            noiseVctr_gyro = randn(3, 1);
            maxNoise_gyro = deg2rad(maxNoise_gyro);            
            noiseVctr_gyro = maxNoise_gyro*noiseVctr_gyro/norm(noiseVctr_gyro);
            w_body_raw_gyro = w_body - constBias_gyro + noiseVctr_gyro;
            w_body_meas_gyro = w_body_raw_gyro;
            prev_w_body_raw_gyro = w_body_raw_gyro; 
            
            app.UIAxes1.BackgroundColor = 'w';
            hold(app.UIAxes1, 'on');
            app.UIAxes1.Title.String = "Measured Angular Velocity (body frame)";
            app.UIAxes1.XLabel.String = 'Elapsed time (s)';
            app.UIAxes1.YLabel.String = 'Angular velocity (deg/s)';
            w_body_meas_gyro_pltX = animatedline(app.UIAxes1, 0, rad2deg(w_body_meas_gyro(1)), 'Color', 'r');
            w_body_meas_gyro_pltY = animatedline(app.UIAxes1, 0, rad2deg(w_body_meas_gyro(2)), 'Color', 'g');
            w_body_meas_gyro_pltZ = animatedline(app.UIAxes1, 0, rad2deg(w_body_meas_gyro(3)), 'Color', 'b');
            zero = animatedline(app.UIAxes1, 0, 0, 'Color', 'k');
            legend(app.UIAxes1, 'ω_B_x', 'ω_B_y', 'ω_B_z');            
            hold(app.UIAxes1, 'off');
            
        end
        
       function setupErrorGyro(app)
            
            global w_desired w_body_meas_gyro 
            global error_w_plX error_w_plY error_w_plZ error_w
            
            error_w = abs(w_desired - w_body_meas_gyro);
                        
            app.UIAxes4.BackgroundColor = 'w';
            hold(app.UIAxes4, 'on');
            app.UIAxes4.Title.String = "Angular velocity error";
            app.UIAxes4.XLabel.String = 'Elapsed time (s)';
            app.UIAxes4.YLabel.String = 'Absolut error (deg/s)';
            grid(app.UIAxes4, 'on');
            error_w_plX = animatedline(app.UIAxes4, 0, rad2deg(error_w(1)), 'Color', 'r');
            error_w_plY = animatedline(app.UIAxes4, 0, rad2deg(error_w(2)), 'Color', 'g');
            error_w_plZ = animatedline(app.UIAxes4, 0, rad2deg(error_w(3)), 'Color', 'b');
            legend(app.UIAxes4, 'E_x', 'E_y', 'E_z');            
            hold(app.UIAxes4, 'off');
            
        end
        
        function setupMagnetorquerPlot(app)
            
            global bDotGain_x bDotGain_y bDotGain_z nCoils secArea
            global w_desired w_body_meas_gyro magField_body maxCurr
            global curr T_mag curr_pltX curr_pltY curr_pltZ bDotGain
                        
            bDotGain = [bDotGain_x; bDotGain_y; bDotGain_z];
            
            curr = (-bDotGain./(nCoils.*secArea)).*cross(w_desired - w_body_meas_gyro, magField_body);
            
            if (abs(curr(1)) > maxCurr(1))
                curr = curr * maxCurr(1) / abs(curr(1));
            end
            if (abs(curr(2)) > maxCurr(2))
                curr = curr * maxCurr(2) / abs(curr(2));
            end
            if (abs(curr(3)) > maxCurr(3))
                curr = curr * maxCurr(3) / abs(curr(3));
            end
            
            T_mag = (nCoils .* secArea) .* cross(curr, magField_body);
            
            app.UIAxes2.BackgroundColor = 'w';            
            hold(app.UIAxes2, 'on');
            app.UIAxes2.Title.String = 'Current to Magnetorquers';
            app.UIAxes2.XLabel.String = 'Elapsed Time (s)';
            app.UIAxes2.YLabel.String = 'Current (A)';
            curr_pltX = animatedline(app.UIAxes2, 0, curr(1), 'Color', 'r');
            curr_pltY = animatedline(app.UIAxes2, 0, curr(2), 'Color', 'g');
            curr_pltZ = animatedline(app.UIAxes2, 0, curr(3), 'Color', 'b');
            legend(app.UIAxes2, 'i_x', 'i_y', 'i_z');
            hold(app.UIAxes2, 'off');
            
        end
                
        function setupMagnetometerPlot(app)
            
            global maxNoise_mag magField_body constBias_mag magField_body_meas prev_magField_body_raw
            global magField_body_meas_pltX magField_body_meas_pltY magField_body_meas_pltZ
            global noiseVctr_mag magField_body_raw 
                 
            noiseVctr_mag = randn(3,1);
            noiseVctr_mag = maxNoise_mag*noiseVctr_mag/norm(noiseVctr_mag);
            magField_body_raw = magField_body - constBias_mag + noiseVctr_mag;
            magField_body_meas = magField_body_raw;
            prev_magField_body_raw = magField_body_raw;
            
            app.UIAxes3.BackgroundColor = 'w';
            hold(app.UIAxes3, 'on');
            app.UIAxes3.Title.String = 'Measured Geomagnetic Field (bodyframe)';
            app.UIAxes3.XLabel.String = 'Elapsed time (s)';
            app.UIAxes3.YLabel.String = 'Magnetic Field (T)';
            magField_body_meas_pltX = animatedline(app.UIAxes3, 0, magField_body_meas(1), 'Color', 'r');
            magField_body_meas_pltY = animatedline(app.UIAxes3, 0, magField_body_meas(2), 'Color', 'g');
            magField_body_meas_pltZ = animatedline(app.UIAxes3, 0, magField_body_meas(3), 'Color', 'b');
            legend(app.UIAxes3, 'β_B_x', 'β_B_y', 'β_B_z');
            hold(app.UIAxes3, 'off');
            
        end
                             
        function detumblingManeuver(app, bDotGain_X, bDotGain_Y, bDotGain_Z)
            
            global bDotGain_x bDotGain_y bDotGain_z 
            
            global altitude rot_mat_zyx a stat To quat quat_dot w_body w_body_dot iner
            global magField_i viewAzmth viewElvtn magField_body satTrnsfrm  
            global sampleR_gyro noiseVctr_gyro maxNoise_gyro w_body_meas_gyro w_body_raw_gyro
            global constBias_gyro prev_w_body_raw_gyro lpFilter_gyro
            global w_body_meas_gyro_pltX w_body_meas_gyro_pltY w_body_meas_gyro_pltZ
            global curr T_mag curr_pltX curr_pltY curr_pltZ bDotGain nCoils maxCurr secArea w_desired
            global magField_body_meas_pltX magField_body_meas_pltY magField_body_meas_pltZ magField_body_meas  
            global noiseVctr_mag magField_body_raw maxNoise_mag sampleR_mag prev_magField_body_raw 
            global constBias_mag lpFilter_mag raan_dot arg_dot W w i w_earth zero
            global error_w_plX error_w_plY error_w_plZ
            
            bDotGain_x = bDotGain_X; 
            bDotGain_y = bDotGain_Y;
            bDotGain_z = bDotGain_Z;
            
            app.StartButton.Enable = 'off';
            
            %%% Get constants
            constants(app); 
            
            %%% Get satellite, sensors and actuator properties            
            magnetorquerParameters(app);
            magnetometerParameters(app);
            gyroParameters(app);  
            satelliteParameters(app);                               
                        
            
            %%% Set initial conditions for orbit
            orbitParameters(app);
            
            %%% Set initial conditions for attitude
            attitudeParameters(app);                       
                       
            %%% GUI parameters
            frameRate = 10;             %% [fps] Must be < 15                   
            viewAzmth = 135;            %% [deg]
            viewElvtn = 22.5;           %% [deg]
                                    
            %%% Setup magnetic model (WMM)
            ltitde_orbt = geoc2geod(asind(stat(3)/a),a);
            lngtde_orbt = atan2d(stat(2), stat(1));
            %magField_i = 1e-9*rot_mat_zyx*wrldmagm(altitude, ltitde_orbt, lngtde_orbt, decyear(2020, 1, 1));
            magField_i = 1e-9*rotMatrx321BdyToInrtl(lngtde_orbt, 270 - ltitde_orbt, 0)*wrldmagm(altitude, ltitde_orbt, lngtde_orbt, decyear(2020, 1, 1));
            
            %% Plot setup
            setupSatellitePlot(app);
            setupGyroPlot(app);
            setupErrorGyro(app);
            setupMagnetometerPlot(app);
            setupMagnetorquerPlot(app);
            
            %%% **********************************************************
            %%% Main simulation
            
            pause(1);
            
            t = 0;
            tprev1 = 0;
            tprev2 = 0;
            tprev3 = 0;
            tpause = 0;
            tpause_total = 0;
            t0 = clock;          
            simSpdMltplr = app.SamplingtimesecEditField.Value;
            nOrbits = app.NumberoforbitsEditField.Value;
            tend = (To*nOrbits)/simSpdMltplr;
            
            idx = 1;
            while (app.StopButton.Value == 0 && (t < tend))
                
                t = etime(clock, t0) - tpause_total;
                app.simProgress.Text = [num2str(int8(t/tend*100)), '%'];
                
                if (app.PauseButton.Value == 1)
                    app.PauseButton.Text = 'Resume';
                    tpause = clock;
                    uiwait(app.attitudeWindowUIFigure);
                    if (app.PauseButton.Value == 0)
                        tpause_total = tpause_total + etime(clock, tpause);
                        app.PauseButton.Text = 'Pause';                        
                    else
                        break;
                    end
                end
                
                %% Satellite plot update
                if (t-tprev1 >= 1/frameRate)
                    
                    %% Update position
                    rotMatrx_orbt = rotMatrx313BdyToInrtl(wrapTo360(W + raan_dot * t * simSpdMltplr), i, wrapTo360(w + arg_dot * t * simSpdMltplr));
                    truAnmly_spcrft = wrapTo360((360 / To) * t * simSpdMltplr);
                    prfclPostn_spcrft = [a * cosd(truAnmly_spcrft); a * sind(truAnmly_spcrft); 0];
                    stat = rotMatrx_orbt * prfclPostn_spcrft;
                    
                    %% Update magnetic field
                    ltitde_orbt = geoc2geod(asind(stat(3)/a),a);
                    lngtde_orbt = wrapTo180(atan2d(stat(2), stat(1)) - wrapTo180(w_earth * t * simSpdMltplr));
                    %magField_i = 1e-9*rot_mat_zyx*wrldmagm(altitude, ltitde_orbt, lngtde_orbt, decyear(2020, 1, 1));
                    magField_i = 1e-9*rotMatrx321BdyToInrtl(lngtde_orbt, 270 - ltitde_orbt, 0)*wrldmagm(altitude, ltitde_orbt, lngtde_orbt, decyear(2020, 1, 1));
                    
                    hold(app.AnimationAxes, 'on');
                    quat = quat + (quat_dot/frameRate) * simSpdMltplr;
                    quat = quat / norm(quat);
                    quat_dot = [0, -w_body(1), -w_body(2), -w_body(3); w_body(1), 0, w_body(3), -w_body(2); w_body(2), -w_body(3), 0, w_body(1); w_body(3), w_body(2), -w_body(1), 0] * quat * 0.5;
                    w_body = w_body + (w_body_dot / frameRate) * simSpdMltplr;
                    w_body_dot = iner \ (T_mag - cross(w_body, iner * w_body));
                    magField_body = quatrotate(quat', magField_i')';
                    
                    if (norm(quat(2:4,1)) ~= 0)
                        satTrnsfrm.Matrix = makehgtform('axisrotate', quat(2:4, 1), 2 * acos(quat(1,1)));
                    end
                    
                    hold(app.AnimationAxes, 'off');
                    drawnow;
                    
                    tprev1 = t;
                    
                end
                
                
                %% Gyro and magnetorquer plot update
                if (t - tprev2 >= 1/sampleR_gyro)
                    
                    %% Gyro
                    hold(app.UIAxes1, 'on');
                    noiseVctr_gyro = randn(3, 1);
                    noiseVctr_gyro = maxNoise_gyro*noiseVctr_gyro / norm(noiseVctr_gyro);
                    w_body_raw_gyro = w_body - constBias_gyro + noiseVctr_gyro;
                    w_body_meas_gyro = (1 - lpFilter_gyro) * w_body_raw_gyro + lpFilter_gyro * prev_w_body_raw_gyro;
                    prev_w_body_raw_gyro = w_body_raw_gyro;
                    addpoints(w_body_meas_gyro_pltX, t * simSpdMltplr, rad2deg(w_body_meas_gyro(1)));
                    addpoints(w_body_meas_gyro_pltY, t * simSpdMltplr, rad2deg(w_body_meas_gyro(2)));
                    addpoints(w_body_meas_gyro_pltZ, t * simSpdMltplr, rad2deg(w_body_meas_gyro(3)));
                    addpoints(zero, t * simSpdMltplr, 0);
                    hold(app.UIAxes1, 'off');
                    
                    %% Gyro error
                                 
                    error_w = abs(w_desired - w_body_meas_gyro);
                   
                    hold(app.UIAxes4, 'on');
                    addpoints(error_w_plX, t * simSpdMltplr, rad2deg(error_w(1)));
                    addpoints(error_w_plY, t * simSpdMltplr, rad2deg(error_w(2)));
                    addpoints(error_w_plZ, t * simSpdMltplr, rad2deg(error_w(3)));    
                    hold(app.UIAxes4, 'off');
                    
                    error_mat(idx,1) = rad2deg(error_w(1));
                    error_mat(idx,2) = rad2deg(error_w(2));
                    error_mat(idx,3) = rad2deg(error_w(3));
                    
                    %% Magnetorquer
                    hold(app.UIAxes2, 'on');
                    curr = (-bDotGain ./ (nCoils .* secArea)) .* cross(w_desired - w_body_meas_gyro, magField_body);
                    
                    if (abs(curr(1)) > maxCurr(1))
                        curr = curr * maxCurr(1) / abs(curr(1));
                    end
                    if (abs(curr(2)) > maxCurr(2))
                        curr = curr * maxCurr(2) / abs(curr(2));
                    end
                    if (abs(curr(3)) > maxCurr(3))
                        curr = curr * maxCurr(3) / abs(curr(3));
                    end
                    
                    T_mag = (nCoils .* secArea) .* cross(curr, magField_body);
                    addpoints(curr_pltX, t * simSpdMltplr, curr(1));
                    addpoints(curr_pltY, t * simSpdMltplr, curr(2));
                    addpoints(curr_pltZ, t * simSpdMltplr, curr(3));
                    hold(app.UIAxes2, 'off');
                    
                    curr_mat(idx,1) = curr(1);
                    curr_mat(idx,2) = curr(2);
                    curr_mat(idx,3) = curr(3);
                    
                    idx = idx+1;
                    
                    tprev2 = t;
                    
                end
            
                %% Magnetometer plot update
                if (t - tprev3 >= 1 / sampleR_mag)
                    
                    hold(app.UIAxes3, 'on');
                    noiseVctr_mag = randn(3, 1);
                    noiseVctr_mag = maxNoise_mag * noiseVctr_mag / norm(noiseVctr_mag);
                    magField_body_raw = magField_body - constBias_mag + noiseVctr_mag;
                    magField_body_meas = (1 - lpFilter_mag) * magField_body_raw + lpFilter_mag * prev_magField_body_raw;
                    prev_magField_body_raw = magField_body_raw;
                    addpoints(magField_body_meas_pltX, t * simSpdMltplr, magField_body_meas(1));
                    addpoints(magField_body_meas_pltY, t * simSpdMltplr, magField_body_meas(2));
                    addpoints(magField_body_meas_pltZ, t * simSpdMltplr, magField_body_meas(3));
                    hold(app.UIAxes3, 'off');
                    
                    tprev3 = t;
                    
                end              
                                
            end
            
            assignin('base', 'error_mat', error_mat);
            assignin('base', 'curr_mat', curr_mat);
            
            disp("Run Time (sec): " + tend);
            app.StartButton.Enable = 'on';
            app.StopButton.Enable = 'off'; 
            app.PauseButton.Enable = 'off';
            app.SamplingtimesecEditField.Enable = 'on';
            app.NumberoforbitsEditField.Enable = 'on';
            app.Slider1.Enable = 'on';
            app.Slider2.Enable = 'on';
            app.Slider3.Enable = 'on';
            app.xdegsSpinner.Enable = 'on';
            app.ydegsSpinner.Enable = 'on';
            app.zdegsSpinner.Enable = 'on';
            
        end
        
        function pointingManeuver(app)
            
            app.ControllerPanel.Title = 'PID Controller';
            app.MagnetometerCheckBox.Value = 0;
            app.MagnetorquerCheckBox.Value = 0;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Move GUI to the center of screen
            movegui(app.attitudeWindowUIFigure,"center");
                      
            app.SamplingtimesecEditField.Enable = 'off';
            app.NumberoforbitsEditField.Enable = 'off';            
            app.StartButton.Enable = 'off';
            app.PauseButton.Enable = 'off';
            app.Slider1.Enable = 'off';
            app.Slider2.Enable = 'off';
            app.Slider3.Enable = 'off';
            app.xdegsSpinner.Enable = 'off';
            app.ydegsSpinner.Enable = 'off';
            app.zdegsSpinner.Enable = 'off';
        end

        % Image clicked function: GoBack
        function GoBackImageClicked(app, event)
            delete(app)
            main_window
        end

        % Value changed function: ManeuverDropDown
        function ManeuverDropDownValueChanged(app, event)
            
            value = app.ManeuverDropDown.Value;
            
            if (value == "Detumbling")
                                
                %%% GUI update
                app.ControllerPanel.Title = 'B-dot Controller';
                app.MagnetometerCheckBox.Value = 1;
                app.IMUgyroCheckBox.Value = 1;
                app.MagnetorquerCheckBox.Value = 1;
                app.GainLabel1.Visible = 'on';
                app.GainLabel2.Visible = 'on';
                app.GainLabel3.Visible = 'on';
                app.GainLabel1.Text = 'Gain X';
                app.GainLabel2.Text = 'Gain Y';
                app.GainLabel3.Text = 'Gain Z';
                app.xdegsSpinner.Visible = 'on';
                app.xdegsSpinnerLabel.Visible = 'on';
                app.ydegsSpinner.Visible = 'on';
                app.ydegsSpinnerLabel.Visible = 'on';
                app.zdegsSpinner.Visible = 'on';
                app.zdegsSpinnerLabel.Visible = 'on';
                app.xdegsSpinner.Enable = 'on';
                app.ydegsSpinner.Enable = 'on';
                app.zdegsSpinner.Enable = 'on';
                app.Slider1.Enable = 'on';
                app.Slider2.Enable = 'on';
                app.Slider3.Enable = 'on';                
                
                app.SamplingtimesecEditField.Enable = 'on';
                app.NumberoforbitsEditField.Enable = 'on';
                app.StartButton.Enable = 'on';                
                
            elseif (value == "Sun Tracking")
                %%% GUI update
                app.ControllerPanel.Title = 'PID Controller';
                app.StartButton.Enable = 'on';
                app.PauseButton.Enable = 'on';
                app.SamplingtimesecEditField.Enable = 'on';
                app.NumberoforbitsEditField.Enable = 'on';            
                app.StartButton.Enable = 'on';
                app.PauseButton.Enable = 'on';
                app.GainLabel1.Visible = 'on';
                app.GainLabel2.Visible = 'on';
                app.GainLabel3.Visible = 'on';
                app.GainLabel1.Text = 'Kp';
                app.GainLabel2.Text = 'Ki';
                app.GainLabel3.Text = 'Kd';
                app.Slider1.Enable = 'on';
                app.Slider2.Enable = 'on';
                app.Slider3.Enable = 'on';
                app.xdegsSpinner.Enable = 'on';
                app.ydegsSpinner.Enable = 'on';
                app.zdegsSpinner.Enable = 'on';
                
            elseif (value == '-') 
                %%% GUI update
                app.StartButton.Enable = 'off';
                app.PauseButton.Enable = 'off';
                app.Slider1.Enable = 'off';
                app.Slider2.Enable = 'off';
                app.Slider3.Enable = 'off';
                app.xdegsSpinner.Enable = 'off';
                app.ydegsSpinner.Enable = 'off';
                app.zdegsSpinner.Enable = 'off';
            end
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            cla(app.AnimationAxes, 'reset');
            cla(app.UIAxes1);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            
            app.PauseButton.Enable = 'on';
            app.StopButton.Enable = 'on';
            app.SamplingtimesecEditField.Enable = 'off';
            app.NumberoforbitsEditField.Enable = 'off';
            app.Slider1.Enable = 'off';
            app.Slider2.Enable = 'off';
            app.Slider3.Enable = 'off';
            app.xdegsSpinner.Enable = 'off';
            app.ydegsSpinner.Enable = 'off';
            app.zdegsSpinner.Enable = 'off';
            
            if (app.ManeuverDropDown.Value == "Detumbling")
                %% Define B-dot gains
                bDotGain_X = 1000*app.Slider1.Value;
                bDotGain_Y = 1000*app.Slider2.Value; 
                bDotGain_Z = 1000*app.Slider3.Value;
                detumblingManeuver(app, bDotGain_X, bDotGain_Y, bDotGain_Z);
            elseif (app.ManeuverDropDown.Value == "Detumbling")
                pointingManeuver(app);            
            end
            
        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
            if app.PauseButton.Value == 0
                uiresume(app.attitudeWindowUIFigure);
            end
        end

        % Value changed function: Slider1
        function Slider1ValueChanged(app, event)
            
            value = app.Slider1.Value;
            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MinorTicks(:)));
            % move the slider to that option
            event.Source.Value = event.Source.MinorTicks(minIdx);
            % Override the selected value 
            value = event.Source.MinorTicks(minIdx);
            
        end

        % Value changed function: Slider2
        function Slider2ValueChanged(app, event)
            value = app.Slider2.Value;
            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MinorTicks(:)));
            % move the slider to that option
            event.Source.Value = event.Source.MinorTicks(minIdx);
            % Override the selected value 
            value = event.Source.MinorTicks(minIdx);
        end

        % Value changed function: Slider3
        function Slider3ValueChanged(app, event)
            value = app.Slider3.Value;
            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MinorTicks(:)));
            % move the slider to that option
            event.Source.Value = event.Source.MinorTicks(minIdx);
            % Override the selected value 
            value = event.Source.MinorTicks(minIdx);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.attitudeWindowUIFigure = uifigure('Visible', 'off');
            app.attitudeWindowUIFigure.Name = 'attitudeWindow';
            app.AttitudeSimulatorLabel = uilabel(app.attitudeWindowUIFigure);
            app.AttitudeSimulatorLabel.Text = 'Attitude Simulator';
            app.GoBack = uiimage(app.attitudeWindowUIFigure);
            app.GoBack.ImageClickedFcn = createCallbackFcn(app, @GoBackImageClicked, true);
            app.GoBack.ImageSource = 'arrow icon.png';
            app.SimulationsetupPanel = uipanel(app.attitudeWindowUIFigure);
            app.SimulationsetupPanel.Title = 'Simulation setup';
            app.ManeuverDropDownLabel = uilabel(app.SimulationsetupPanel);
            app.ManeuverDropDownLabel.Text = 'Maneuver';
            app.ManeuverDropDown = uidropdown(app.SimulationsetupPanel);
            app.ManeuverDropDown.Items = {'-', 'Detumbling', 'Sun Tracking'};
            app.ManeuverDropDown.ValueChangedFcn = createCallbackFcn(app, @ManeuverDropDownValueChanged, true);
            app.ManeuverDropDown.Value = '-';
            app.MagnetometerCheckBox = uicheckbox(app.SimulationsetupPanel);
            app.MagnetometerCheckBox.Enable = 'off';
            app.MagnetometerCheckBox.Text = 'Magnetometer';
            app.IMUgyroCheckBox = uicheckbox(app.SimulationsetupPanel);
            app.IMUgyroCheckBox.Enable = 'off';
            app.IMUgyroCheckBox.Text = 'IMU (gyro)';
            app.SunsensorCheckBox = uicheckbox(app.SimulationsetupPanel);
            app.SunsensorCheckBox.Enable = 'off';
            app.SunsensorCheckBox.Text = 'Sun sensor';
            app.ReactionwheelCheckBox = uicheckbox(app.SimulationsetupPanel);
            app.ReactionwheelCheckBox.Enable = 'off';
            app.ReactionwheelCheckBox.Text = 'Reaction wheel';
            app.MagnetorquerCheckBox = uicheckbox(app.SimulationsetupPanel);
            app.MagnetorquerCheckBox.Enable = 'off';
            app.MagnetorquerCheckBox.Text = 'Magnetorquer';
            app.SamplingtimesecEditFieldLabel = uilabel(app.SimulationsetupPanel);
            app.SamplingtimesecEditFieldLabel.Text = 'Sampling time (sec)';
            app.SamplingtimesecEditField = uieditfield(app.SimulationsetupPanel, 'numeric');
            app.SamplingtimesecEditField.Limits = [1 100];
            app.SamplingtimesecEditField.Value = 50;
            app.NumberoforbitsEditFieldLabel = uilabel(app.SimulationsetupPanel);
            app.NumberoforbitsEditFieldLabel.Text = 'Number of orbits';
            app.NumberoforbitsEditField = uieditfield(app.SimulationsetupPanel, 'numeric');
            app.NumberoforbitsEditField.Limits = [1 10];
            app.NumberoforbitsEditField.Value = 1;
            app.AnimationPanel = uipanel(app.attitudeWindowUIFigure);
            app.AnimationPanel.Title = 'Animation';
            app.AnimationAxes = uiaxes(app.AnimationPanel);
            title(app.AnimationAxes, '')
            xlabel(app.AnimationAxes, '')
            ylabel(app.AnimationAxes, '')
            app.PlotsPanel = uipanel(app.attitudeWindowUIFigure);
            app.PlotsPanel.Title = 'Plots';
            app.TabGroup = uitabgroup(app.PlotsPanel);
            app.Plot1 = uitab(app.TabGroup);
            app.Plot1.Title = 'a';
            app.UIAxes1 = uiaxes(app.Plot1);
            title(app.UIAxes1, '')
            xlabel(app.UIAxes1, '')
            ylabel(app.UIAxes1, '')
            app.Plot4 = uitab(app.TabGroup);
            app.Plot4.Title = 'b';
            app.UIAxes4 = uiaxes(app.Plot4);
            title(app.UIAxes4, '')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.Plot2 = uitab(app.TabGroup);
            app.Plot2.Title = 'c';
            app.UIAxes2 = uiaxes(app.Plot2);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.Plot3 = uitab(app.TabGroup);
            app.Plot3.Title = 'd';
            app.UIAxes3 = uiaxes(app.Plot3);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.ControllerPanel = uipanel(app.attitudeWindowUIFigure);
            app.ControllerPanel.Title = 'Controller';
            app.Slider1 = uislider(app.ControllerPanel);
            app.Slider1.Limits = [5 65];
            app.Slider1.ValueChangedFcn = createCallbackFcn(app, @Slider1ValueChanged, true);
            app.Slider1.MinorTicks = [5 10 15 20 25 30 35 40 45 50 55 60 65];
            app.Slider1.Value = 30;
            app.Slider2 = uislider(app.ControllerPanel);
            app.Slider2.Limits = [5 65];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.Slider2.MinorTicks = [5 10 15 20 25 30 35 40 45 50 55 60 65];
            app.Slider2.Value = 30;
            app.Slider3 = uislider(app.ControllerPanel);
            app.Slider3.Limits = [5 65];
            app.Slider3.ValueChangedFcn = createCallbackFcn(app, @Slider3ValueChanged, true);
            app.Slider3.MinorTicks = [5 10 15 20 25 30 35 40 45 50 55 60 65];
            app.Slider3.Value = 30;
            app.GainLabel1 = uilabel(app.ControllerPanel);
            app.GainLabel1.Text = 'Gain';
            app.GainLabel2 = uilabel(app.ControllerPanel);
            app.GainLabel2.Text = 'Gain';
            app.GainLabel3 = uilabel(app.ControllerPanel);
            app.GainLabel3.Text = 'Gain';
            app.xdegsSpinnerLabel = uilabel(app.ControllerPanel);
            app.xdegsSpinnerLabel.Text = 'ω₀, x (deg/s)';
            app.xdegsSpinner = uispinner(app.ControllerPanel);
            app.xdegsSpinner.Step = 0.5;
            app.xdegsSpinner.Limits = [0 3];
            app.xdegsSpinner.Value = 0.5;
            app.ydegsSpinnerLabel = uilabel(app.ControllerPanel);
            app.ydegsSpinnerLabel.Text = 'ω₀, y (deg/s)';
            app.ydegsSpinner = uispinner(app.ControllerPanel);
            app.ydegsSpinner.Step = 0.5;
            app.ydegsSpinner.Limits = [0 3];
            app.ydegsSpinner.Value = 1;
            app.zdegsSpinnerLabel = uilabel(app.ControllerPanel);
            app.zdegsSpinnerLabel.Text = 'ω₀, z (deg/s)';
            app.zdegsSpinner = uispinner(app.ControllerPanel);
            app.zdegsSpinner.Step = 0.5;
            app.zdegsSpinner.Limits = [0 3];
            app.zdegsSpinner.Value = 2;
            app.StartButton = uibutton(app.attitudeWindowUIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.PauseButton = uibutton(app.attitudeWindowUIFigure, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Enable = 'off';
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.attitudeWindowUIFigure, 'state');
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.simProgress = uilabel(app.attitudeWindowUIFigure);
            app.simProgress.Text = '';
            % Show the figure after all components are created
            app.attitudeWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = attitude_sim_window

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.attitudeWindowUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.attitudeWindowUIFigure)
        end
    end
end