classdef components_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        componentsWindowUIFigure  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        ADCSComponentsLabel       matlab.ui.control.Label
        APSCOSmallStudentSatellite1SSS1Label  matlab.ui.control.Label
        SensorsPanel              matlab.ui.container.Panel
        MagnetometerButton        matlab.ui.control.Button
        GyroscopeIMUButton        matlab.ui.control.Button
        SunSensorButton           matlab.ui.control.Button
        ActuatorsPanel            matlab.ui.container.Panel
        ReactionWheelButton       matlab.ui.control.Button
        MagnetorquersButton       matlab.ui.control.Button
        ACUButton                 matlab.ui.control.Button
        Panel                     matlab.ui.container.Panel
        Image                     matlab.ui.control.Image
        ComponentName             matlab.ui.control.Label
        TextArea                  matlab.ui.control.TextArea
        GoBack                    matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Move GUI to the center of screen
            movegui(app.componentsWindowUIFigure,"center");
        end

        % Image clicked function: GoBack
        function GoBackClicked(app, event)
            delete(app)
            main_window
        end

        % Button pushed function: MagnetometerButton
        function MagnetometerButtonPushed(app, event)
            img = imread('magnetometer.png');
            app.Image.ImageSource = img;
            app.ComponentName.Text = "HMR2300";
            app.TextArea.Value = ["⚬ Mass: 98 g", "⚬ Dimensions: 82.6 x 38.1 x 22.3 mm","⚬ Accuracy: 120 nT","⚬ Range: ±2 Gauss",...
                "⚬ Power supply: 6V to 15V DC","⚬ Operating temperature: -40°C to +85°C",...
                "⚬ Communication: Serial (full-duplex) RS-232 or Serial (half-duplex) RS-485"];
            app.TextArea.Visible = 'on';
        end

        % Button pushed function: GyroscopeIMUButton
        function GyroscopeIMUButtonPushed(app, event)
            img = imread('IMU.png');
            app.Image.ImageSource = img;
            app.ComponentName.Text = "ADIS16445";
            app.TextArea.Value = ["⚬ Mass: 15 g","⚬ Dimensions: 24.1 x 37.7 x 10.8 mm", "⚬ Gyro drift range: 0.003°/h to 1°/h"...
                "⚬ Dynamic range: ±250°/sec", "⚬ Power supply: 3.15V to 3.45V", "⚬ Operating temperature: -40°C to +105°C",...
                "⚬ Communication: SPI"];
            app.TextArea.Visible = 'on';
        end

        % Button pushed function: SunSensorButton
        function SunSensorButtonPushed(app, event)
            img = imread('sun_sensor.png');
            app.Image.ImageSource = img;
            app.ComponentName.Text = "SSOC-D60";
            app.TextArea.Value = ["⚬ Mass: 35.5 ± 0.2 g","⚬ Dimensions: 60 x 30 x 12 mm", "⚬ Accuracy: < 0.3° (3σ) ", "⚬ Precision: < 0.05°",...
                "⚬ Power supply: 5V","⚬ Operating temperature: -40°C to +85°C","⚬ Communication: SPI or UART (RS-422)"];
            app.TextArea.Visible = 'on';
        end

        % Button pushed function: ReactionWheelButton
        function ReactionWheelButtonPushed(app, event)
            img = imread('rw.png');
            app.Image.ImageSource = img;
            app.ComponentName.Text = "V70 (developed by USTC)";
            app.TextArea.Value = ["⚬ Mass: 450 g", "⚬ Dimensions: 70 x 70 x 73 mm", "⚬ Moment of inertia: 0.0001067 kg∙m²", ...
                "⚬ Speed accuracy: 0.5 RPM","⚬ Acceleration performance: 45s", "⚬ Maximum torque: 0.01 N∙m",...
                "⚬ Maximum angular mom.: 0.067 N∙m∙s","⚬ Operating voltage: 10V to 14V",...
                "⚬ Operating temperature: -20°C to +50°C","⚬ Communication: CAN (500 kbps)"];
            app.TextArea.Visible = 'on';
        end

        % Button pushed function: MagnetorquersButton
        function MagnetorquersButtonPushed(app, event)
            img = imread('magnetorquer.png');
            app.Image.ImageSource = img;
            app.ComponentName.Text = "Customized (1 of the 3 axis)";
            app.TextArea.Value = ["⚬ Mass: 2.8 kg","⚬ Dimensions (controller): 250 x 66 x 33.5 mm",...
                "⚬ Dimensions (controller): 136 x 40 x 86 mm", "⚬ Maximum magnetic moment: 12 A∙ּm²",...
                "⚬ Operating voltage: 9V","⚬ Operating temperature: -20°C to +65°C", "⚬ Communication: RS-485-2"];
            app.TextArea.Visible = 'on';
        end

        % Button pushed function: ACUButton
        function ACUButtonPushed(app, event)
            img = imread('ACU.png');
            app.Image.ImageSource = img;
            app.ComponentName.Text = "SSS-1 ACU";
            app.TextArea.Value = ["⚬ Mass: 400 g","⚬ Dimensions: 120 x 135 x 128 mm","⚬ Maximum current: 240 mA",...
                "⚬ Operating voltage: 9V", "⚬ Operating temperature: -40°C to +85°C","⚬ Communication: CAN (500 kbps)"];
            app.TextArea.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.componentsWindowUIFigure = uifigure('Visible', 'off');
            app.componentsWindowUIFigure.Name = 'componentsWindow';
            app.GridLayout = uigridlayout(app.componentsWindowUIFigure);
            app.ADCSComponentsLabel = uilabel(app.GridLayout);
            app.ADCSComponentsLabel.Text = 'ADCS Components';
            app.APSCOSmallStudentSatellite1SSS1Label = uilabel(app.GridLayout);
            app.APSCOSmallStudentSatellite1SSS1Label.Text = 'APSCO Small Student Satellite-1 (SSS-1)';
            app.SensorsPanel = uipanel(app.GridLayout);
            app.SensorsPanel.Title = 'Sensors';
            app.MagnetometerButton = uibutton(app.SensorsPanel, 'push');
            app.MagnetometerButton.ButtonPushedFcn = createCallbackFcn(app, @MagnetometerButtonPushed, true);
            app.MagnetometerButton.Text = 'Magnetometer';
            app.GyroscopeIMUButton = uibutton(app.SensorsPanel, 'push');
            app.GyroscopeIMUButton.ButtonPushedFcn = createCallbackFcn(app, @GyroscopeIMUButtonPushed, true);
            app.GyroscopeIMUButton.Text = 'Gyroscope (IMU)';
            app.SunSensorButton = uibutton(app.SensorsPanel, 'push');
            app.SunSensorButton.ButtonPushedFcn = createCallbackFcn(app, @SunSensorButtonPushed, true);
            app.SunSensorButton.Text = 'Sun Sensor';
            app.ActuatorsPanel = uipanel(app.GridLayout);
            app.ActuatorsPanel.Title = 'Actuators';
            app.ReactionWheelButton = uibutton(app.ActuatorsPanel, 'push');
            app.ReactionWheelButton.ButtonPushedFcn = createCallbackFcn(app, @ReactionWheelButtonPushed, true);
            app.ReactionWheelButton.Text = 'Reaction Wheel';
            app.MagnetorquersButton = uibutton(app.ActuatorsPanel, 'push');
            app.MagnetorquersButton.ButtonPushedFcn = createCallbackFcn(app, @MagnetorquersButtonPushed, true);
            app.MagnetorquersButton.Text = 'Magnetorquers';
            app.ACUButton = uibutton(app.ActuatorsPanel, 'push');
            app.ACUButton.ButtonPushedFcn = createCallbackFcn(app, @ACUButtonPushed, true);
            app.ACUButton.Text = 'ACU';
            app.Panel = uipanel(app.GridLayout);
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = 'img.png';
            app.ComponentName = uilabel(app.Panel);
            app.ComponentName.Text = '';
            app.TextArea = uitextarea(app.Panel);
            app.TextArea.Editable = 'off';
            app.TextArea.Visible = 'off';
            app.GoBack = uiimage(app.GridLayout);
            app.GoBack.ImageClickedFcn = createCallbackFcn(app, @GoBackClicked, true);
            app.GoBack.ImageSource = 'arrow icon.png';
            % Show the figure after all components are created
            app.componentsWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = components_window

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.componentsWindowUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.componentsWindowUIFigure)
        end
    end
end