classdef main_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainWindowUIFigure            matlab.ui.Figure
        ADCSVirtualEducationalPlatformLabel  matlab.ui.control.Label
        APSCOSmallStudentSatellite1SSS1Label  matlab.ui.control.Label
        GeneralInfoPanel              matlab.ui.container.Panel
        Mass30kgLabel                 matlab.ui.control.Label
        Dimensions350x350x650mmLabel  matlab.ui.control.Label
        StatusLabel                   matlab.ui.control.Label
        ACTIVELabel                   matlab.ui.control.Label
        OrbitLEOSSOLabel              matlab.ui.control.Label
        ADCSComponentsButton          matlab.ui.control.Button
        structuralAnalysisButton      matlab.ui.control.Button
        hyperlink                     matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        OrbitSimulatorButton          matlab.ui.control.Button
        AttitudeSimulatorButton       matlab.ui.control.Button
        ADCSSimulinkButton            matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           % Move GUI to the center of screen
            movegui(app.mainWindowUIFigure,"center");
        end

        % Button pushed function: ADCSComponentsButton
        function ADCSComponentsButtonPushed(app, event)
            components_window
            delete(app)
        end

        % Button pushed function: structuralAnalysisButton
        function structuralAnalysisButtonPushed(app, event)
            structural_analysis_window
            delete(app)
        end

        % Button pushed function: OrbitSimulatorButton
        function OrbitSimulatorButtonPushed(app, event)
            orbit_sim_window
            delete(app)
        end

        % Button pushed function: AttitudeSimulatorButton
        function AttitudeSimulatorButtonPushed(app, event)
            attitude_sim_window
            delete(app)
        end

        % Button pushed function: ADCSSimulinkButton
        function ADCSSimulinkButtonPushed(app, event)
            %%% Open Simulink model of SSS-1 ADCS (BUAA property)
            adcsim_init;
            adcsim_sl;
            
            %%% Open MATLAB CubeSat Simulation
            %openProject("C:\Users\letic\MATLAB\Projects\CubeSat");
            %sim("C:\Users\letic\OneDrive\Desktop\Educational_ADCS_Platform\SSS1.slx")
        end

        % Image clicked function: hyperlink
        function hyperlinkClicked(app, event)
            dos('explorer https://ev.buaa.edu.cn/info/1134/3170.htm'); % Open BUAA page on browser
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainWindowUIFigure = uifigure('Visible', 'off');
            app.mainWindowUIFigure.Name = 'mainWindow';
            app.ADCSVirtualEducationalPlatformLabel = uilabel(app.mainWindowUIFigure);
            app.ADCSVirtualEducationalPlatformLabel.Text = 'ADCS Virtual Educational Platform';
            app.APSCOSmallStudentSatellite1SSS1Label = uilabel(app.mainWindowUIFigure);
            app.APSCOSmallStudentSatellite1SSS1Label.Text = 'APSCO Small Student Satellite-1 (SSS-1)';
            app.GeneralInfoPanel = uipanel(app.mainWindowUIFigure);
            app.GeneralInfoPanel.Title = 'General Info';
            app.Mass30kgLabel = uilabel(app.GeneralInfoPanel);
            app.Mass30kgLabel.Text = 'Mass: 30 kg';
            app.Dimensions350x350x650mmLabel = uilabel(app.GeneralInfoPanel);
            app.Dimensions350x350x650mmLabel.Text = 'Dimensions: 350x350x650 mm';
            app.StatusLabel = uilabel(app.GeneralInfoPanel);
            app.StatusLabel.Text = 'Status: ';
            app.ACTIVELabel = uilabel(app.GeneralInfoPanel);
            app.ACTIVELabel.Text = 'ACTIVE';
            app.OrbitLEOSSOLabel = uilabel(app.GeneralInfoPanel);
            app.OrbitLEOSSOLabel.Text = 'Orbit: LEO (SSO)';
            app.ADCSComponentsButton = uibutton(app.GeneralInfoPanel, 'push');
            app.ADCSComponentsButton.ButtonPushedFcn = createCallbackFcn(app, @ADCSComponentsButtonPushed, true);
            app.ADCSComponentsButton.Tag = 'buttonComponents';
            app.ADCSComponentsButton.Text = 'ADCS Components';
            app.structuralAnalysisButton = uibutton(app.GeneralInfoPanel, 'push');
            app.structuralAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @structuralAnalysisButtonPushed, true);
            app.structuralAnalysisButton.Text = 'Structural Analysis of ADCS Components';
            app.hyperlink = uiimage(app.GeneralInfoPanel);
            app.hyperlink.ImageClickedFcn = createCallbackFcn(app, @hyperlinkClicked, true);
            app.hyperlink.ImageSource = 'hyperlink icon.png';
            app.Image = uiimage(app.GeneralInfoPanel);
            app.Image.ImageSource = 'CATIA model expanded view.png';
            app.OrbitSimulatorButton = uibutton(app.mainWindowUIFigure, 'push');
            app.OrbitSimulatorButton.ButtonPushedFcn = createCallbackFcn(app, @OrbitSimulatorButtonPushed, true);
            app.OrbitSimulatorButton.Text = 'Orbit Simulator';
            app.AttitudeSimulatorButton = uibutton(app.mainWindowUIFigure, 'push');
            app.AttitudeSimulatorButton.ButtonPushedFcn = createCallbackFcn(app, @AttitudeSimulatorButtonPushed, true);
            app.AttitudeSimulatorButton.Text = 'Attitude Simulator';
            app.ADCSSimulinkButton = uibutton(app.mainWindowUIFigure, 'push');
            app.ADCSSimulinkButton.ButtonPushedFcn = createCallbackFcn(app, @ADCSSimulinkButtonPushed, true);
            app.ADCSSimulinkButton.Text = {'ADCS'; 'Simulink Model of SSS-1'};
            % Show the figure after all components are created
            app.mainWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_window

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainWindowUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainWindowUIFigure)
        end
    end
end