classdef orbit_sim_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        orbitWindowUIFigure             matlab.ui.Figure
        OrbitSimulatorLabel             matlab.ui.control.Label
        GoBack                          matlab.ui.control.Image
        SelectKeplerianElementsPanel    matlab.ui.container.Panel
        SemimajoraxismEditFieldLabel    matlab.ui.control.Label
        SemiMajorAxisEditField          matlab.ui.control.NumericEditField
        EccentricityLabel               matlab.ui.control.Label
        EccentricityEditField           matlab.ui.control.NumericEditField
        ArgumentofperigeedegEditFieldLabel  matlab.ui.control.Label
        ArgumentOfPerigeeEditField      matlab.ui.control.NumericEditField
        RightascensionofascendingnodedegEditFieldLabel  matlab.ui.control.Label
        RightAscensionEditField         matlab.ui.control.NumericEditField
        InclinationEditFieldLabel       matlab.ui.control.Label
        InclinationEditField            matlab.ui.control.NumericEditField
        MeananomalydegEditFieldLabel    matlab.ui.control.Label
        MeanAnomalyEditField            matlab.ui.control.NumericEditField
        SatelliteTLEDropDownLabel       matlab.ui.control.Label
        SatelliteTLEDropDown            matlab.ui.control.DropDown
        AutoCheckBox                    matlab.ui.control.CheckBox
        OtherconfigPanel                matlab.ui.container.Panel
        EarthrotationangledegEditFieldLabel  matlab.ui.control.Label
        EarthrotationangledegEditField  matlab.ui.control.NumericEditField
        NofperiodsEditFieldLabel        matlab.ui.control.Label
        NofperiodsEditField             matlab.ui.control.NumericEditField
        InertialReferenceFramePanel     matlab.ui.container.Panel
        InertialUIAxes                  matlab.ui.control.UIAxes
        ECIGroundtrackPanel             matlab.ui.container.Panel
        GroundUIAxes                    matlab.ui.control.UIAxes
        EarthCenteredInertialFramePanel  matlab.ui.container.Panel
        ECIUIAxes                       matlab.ui.control.UIAxes
        PlotButton                      matlab.ui.control.Button
        AnimationPanel                  matlab.ui.container.Panel
        StopButton                      matlab.ui.control.Button
        AnimateCheckBox                 matlab.ui.control.CheckBox
        PauseButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
    end
    
    methods (Access = public)
        
        function updateKepel(app,kepel)
            
            app.SemiMajorAxisEditField.Value = kepel(1,1); % semi-major axis (m)
            app.EccentricityEditField.Value = kepel(1,2); % eccentricity
            app.InclinationEditField.Value = rad2deg(kepel(1,3)); % inclination
            app.RightAscensionEditField.Value = rad2deg(kepel(1,4)); % right ascension of ascending node
            app.ArgumentOfPerigeeEditField.Value = rad2deg(kepel(1,5)); % argument of perigee
            app.MeanAnomalyEditField.Value = rad2deg(kepel(1,6)); % mean anomaly
            
        end
        
        function valuesKepel = getValuesKepel(app)
            
            valuesKepel(1,1) = app.SemiMajorAxisEditField.Value;
            valuesKepel(1,2) = app.EccentricityEditField.Value;
            valuesKepel(1,3) = app.InclinationEditField.Value;
            valuesKepel(1,4) = app.RightAscensionEditField.Value;
            valuesKepel(1,5) = app.ArgumentOfPerigeeEditField.Value;
            valuesKepel(1,6) = app.MeanAnomalyEditField.Value;
            
        end
        
        function valuesConfig = getValuesConfig(app)
            
            valuesConfig(1,1) = app.EarthrotationangledegEditField.Value;
            valuesConfig(1,2) = app.NofperiodsEditField.Value;
            
        end
        
        function disableFields(app)
            
            % Kepel fields
            app.SemiMajorAxisEditField.Enable = 'off';
            app.EccentricityEditField.Enable = 'off';
            app.InclinationEditField.Enable = 'off';
            app.RightAscensionEditField.Enable = 'off';
            app.ArgumentOfPerigeeEditField.Enable = 'off';
            app.MeanAnomalyEditField.Enable = 'off';
            % Other config. fields
            app.EarthrotationangledegEditField.Enable = 'off';
            app.NofperiodsEditField.Enable = 'off';
            % Drop down field
            app.SatelliteTLEDropDown.Enable = 'off';
            
        end
        
        function enableFields(app)
            
            % Kepel fields
            app.SemiMajorAxisEditField.Enable = 'on';
            app.EccentricityEditField.Enable = 'on';
            app.InclinationEditField.Enable = 'on';
            app.RightAscensionEditField.Enable = 'on';
            app.ArgumentOfPerigeeEditField.Enable = 'on';
            app.MeanAnomalyEditField.Enable = 'on';
            % Other config. fields
            app.EarthrotationangledegEditField.Enable = 'on';
            app.NofperiodsEditField.Enable = 'on';
            % Drop down field
            app.SatelliteTLEDropDown.Enable = 'on';
            
        end
        
        function disableAll(app)
            
            % Kepel fields
            app.SemiMajorAxisEditField.Enable = 'off';
            app.EccentricityEditField.Enable = 'off';
            app.InclinationEditField.Enable = 'off';
            app.RightAscensionEditField.Enable = 'off';
            app.ArgumentOfPerigeeEditField.Enable = 'off';
            app.MeanAnomalyEditField.Enable = 'off';
            % Other config. fields
            app.EarthrotationangledegEditField.Enable = 'off';
            app.NofperiodsEditField.Enable = 'off';
            % Drop down field
            app.SatelliteTLEDropDown.Enable = 'off';
            % Start button --- the animation has to be complete to perform
            % another orbit simulation
            app.PlotButton.Enable = 'off';
            % Check boxes
            app.AutoCheckBox.Enable = 'off';
            app.AnimateCheckBox.Enable = 'off';
            
        end
        
        function enableAll(app)
            
            % Kepel fields
            app.SemiMajorAxisEditField.Enable = 'on';
            app.EccentricityEditField.Enable = 'on';
            app.InclinationEditField.Enable = 'on';
            app.RightAscensionEditField.Enable = 'on';
            app.ArgumentOfPerigeeEditField.Enable = 'on';
            app.MeanAnomalyEditField.Enable = 'on';
            % Other config. fields
            app.EarthrotationangledegEditField.Enable = 'on';
            app.NofperiodsEditField.Enable = 'on';
            % Drop down field
            app.SatelliteTLEDropDown.Enable = 'on';
            % Start button
            app.PlotButton.Enable = 'on';
            % Check boxes
            app.AutoCheckBox.Enable = 'on';
            app.AnimateCheckBox.Enable = 'on';
            
        end
        
        function animateSimulatedOrbit(app)
            
            % Global variables
            global POS_EARTH POS_COAST LON LAT TOPOGRAPHY row col long_corr lat_corr irf_axes efrf_axes
            global theta_z t era pause_resume stop
            global coastline_color satellite_color orbit_color velocity_color radial_color irf_color efrf_color
            global x_sat y_sat z_sat v_x_sat v_y_sat v_z_sat X_sat Y_sat Z_sat V_X_sat V_Y_sat V_Z_sat phi_sat phi_sat_corr lamda_sat lamda_sat_corr
            
            disableAll(app);
            app.PauseButton.Enable = 'on';
            app.StopButton.Enable = 'on';
            
            %Calculate initial states
            X_EARTH = reshape(POS_EARTH(1,:),row,col);
            Y_EARTH = reshape(POS_EARTH(2,:),row,col);
            Z_EARTH = reshape(POS_EARTH(3,:),row,col);
            X_COAST = POS_COAST(1,:)';
            Y_COAST = POS_COAST(2,:)';
            Z_COAST = POS_COAST(3,:)';
            POS_EARTH_NEW = rotmaz(-theta_z)*POS_EARTH;
            POS_COAST_NEW = rotmaz(-theta_z)*POS_COAST;
            X_EARTH_NEW = reshape(POS_EARTH_NEW(1,:),row,col);
            Y_EARTH_NEW = reshape(POS_EARTH_NEW(2,:),row,col);
            Z_EARTH_NEW = reshape(POS_EARTH_NEW(3,:),row,col);
            X_COAST_NEW = POS_COAST_NEW(1,:)';
            Y_COAST_NEW = POS_COAST_NEW(2,:)';
            Z_COAST_NEW = POS_COAST_NEW(3,:)';
            IRF_AXES = rotmaz(-theta_z)*irf_axes;
            EFRF_AXES = rotmaz(theta_z)*efrf_axes;
            
            %% ------------ Plot to Inertial Reference Frame ----------- %%
                      
            %Plot Earth
            h1a = surf(app.InertialUIAxes,X_EARTH_NEW,Y_EARTH_NEW,Z_EARTH_NEW,TOPOGRAPHY);
            [cmap,~] = demcmap([min(min(TOPOGRAPHY)) max(max(TOPOGRAPHY))]);
            colormap(app.InertialUIAxes, cmap);
            shading(app.InertialUIAxes,'interp')
            hold(app.InertialUIAxes, 'on')
            
            %Plot coastline
            h2a = plot3(app.InertialUIAxes,X_COAST_NEW,Y_COAST_NEW,Z_COAST_NEW,'Color',coastline_color);
            
            %Plot satellite orbit
            h3a = plot3(app.InertialUIAxes,x_sat(1:362),y_sat(1:362),z_sat(1:362),'Color',orbit_color,'Linewidth',2);
            
            %Plot satellite
            h4a = scatter3(app.InertialUIAxes,x_sat(1,1),y_sat(1,1),z_sat(1,1),100,'filled','MarkerEdgeColor',satellite_color,'MarkerFaceColor',satellite_color);
            
            %Plot satellite velocity
            h5a = quiver3(app.InertialUIAxes,x_sat(1,1),y_sat(1,1),z_sat(1,1),v_x_sat(1,1)*1000,v_y_sat(1,1)*1000,v_z_sat(1,1)*1000,'Color',velocity_color,'Linewidth',2);
            
            %Plot satellite radial distance
            h6a = plot3(app.InertialUIAxes,[0 x_sat(1,1)],[0 y_sat(1,1)],[0 z_sat(1,1)],'Color',radial_color);
            
            %Plot Inertial Reference Frame axes
            h7a = quiver3(app.InertialUIAxes,[0;0;0],[0;0;0],[0;0;0],irf_axes(:,1),irf_axes(:,2),irf_axes(:,3),'Color',irf_color,'Linewidth',2,'ShowArrowHead','off');
            
            %Plot Earth Fixed Reference Frame axes
            h8a = quiver3(app.InertialUIAxes,[0;0;0],[0;0;0],[0;0;0],EFRF_AXES(:,1),EFRF_AXES(:,2),EFRF_AXES(:,3),'Color',efrf_color,'Linewidth',2,'ShowArrowHead','off');

            app.InertialUIAxes.View = [-24,48];
            axis(app.InertialUIAxes, 'equal')
            axis(app.InertialUIAxes, 'off')
            app.InertialUIAxes.XGrid = 'off';
            app.InertialUIAxes.YGrid = 'off';
            app.InertialUIAxes.ZGrid = 'off';
            app.InertialUIAxes.Box = 'off';
            rotate3d(app.InertialUIAxes, 'on')
            hold(app.InertialUIAxes, 'off')
            
            
            %% ------------ Plot to Earth Fixed Frame ----------- %%
                
            % Plot Earth
            h1b = surf(app.ECIUIAxes,X_EARTH,Y_EARTH,Z_EARTH,TOPOGRAPHY);
            colormap(app.ECIUIAxes, cmap);
            shading(app.ECIUIAxes,'interp')
            hold(app.ECIUIAxes,'on')
            
            % Plot coastline
            h2b = plot3(app.ECIUIAxes,X_COAST,Y_COAST,Z_COAST,'Color',coastline_color);
            
            % Plot satellite orbit
            h3b = plot3(app.ECIUIAxes,X_sat,Y_sat,Z_sat,'Color',orbit_color,'Linewidth',2);
            
            %Plot satellite
            h4b = scatter3(app.ECIUIAxes,X_sat(1,1),Y_sat(1,1),Z_sat(1,1),100,'filled','MarkerEdgeColor',satellite_color,'MarkerFaceColor',satellite_color);
            
            %Plot satellite velocity
            h5b = quiver3(app.ECIUIAxes,X_sat(1,1),Y_sat(1,1),Z_sat(1,1),V_X_sat(1,1)*1000,V_Y_sat(1,1)*1000,V_Z_sat(1,1)*1000,'Color',velocity_color,'Linewidth',2);
            
            %Plot satellite radial distance
            h6b = plot3(app.ECIUIAxes, [0 X_sat(1,1)],[0 Y_sat(1,1)],[0 Z_sat(1,1)],'Color',radial_color);
            
            %Plot Inertial Reference Frame axes
            h7b = quiver3(app.ECIUIAxes,[0;0;0],[0;0;0],[0;0;0],IRF_AXES(:,1),IRF_AXES(:,2),IRF_AXES(:,3),'Color',irf_color,'Linewidth',2,'ShowArrowHead','off');
            
            %Plot Earth Fixed Reference Frame axes
            h8b = quiver3(app.ECIUIAxes,[0;0;0],[0;0;0],[0;0;0],efrf_axes(:,1),efrf_axes(:,2),efrf_axes(:,3),'Color',efrf_color,'Linewidth',2,'ShowArrowHead','off');
            
            app.ECIUIAxes.View = [-24,48];
            axis(app.ECIUIAxes, 'equal')
            axis(app.ECIUIAxes, 'off')
            app.ECIUIAxes.XGrid = 'off';
            app.ECIUIAxes.YGrid = 'off';
            app.ECIUIAxes.ZGrid = 'off';
            app.ECIUIAxes.Box = 'off';
            rotate3d(app.ECIUIAxes, 'on')
            hold(app.ECIUIAxes, 'off')
            
            % --- Plot to Earth Fixed Reference Frame (Ground Tracks) --- %
 
            %Plot Earth
            h1c = pcolor(app.GroundUIAxes,LON,LAT,TOPOGRAPHY);
            colormap(app.GroundUIAxes, cmap);
            shading(app.GroundUIAxes, 'interp')            
            hold(app.GroundUIAxes, 'on')
            
            %Plot coastline
            h2c = plot(app.GroundUIAxes,long_corr,lat_corr,'Color',coastline_color);
            
            %Plot satellite orbit
            h3c = plot(app.GroundUIAxes,lamda_sat_corr,phi_sat_corr,'Color',orbit_color,'Linewidth',2);
            
            %Plot satellite
            h4c = scatter(app.GroundUIAxes,lamda_sat(1,1),phi_sat(1,1),100,'filled','MarkerEdgeColor',satellite_color,'MarkerFaceColor',satellite_color);
            
            
            axis(app.GroundUIAxes, 'equal')
            axis(app.GroundUIAxes, 'off')
            app.GroundUIAxes.XGrid = 'off';
            app.GroundUIAxes.YGrid = 'off';
            app.GroundUIAxes.ZGrid = 'off';
            rotate3d(app.GroundUIAxes, 'on')
            hold(app.GroundUIAxes, 'off')
            
            % Update the plot data
            for ii = 2:size(t,1)
                
                while (pause_resume == 1)
                    pause(1);
                    if(stop == 1)
                        break;
                    end
                end
                
                if (stop == 1)
                    break;
                end
                
                %Calculate states for each epoch
                POS_EARTH_NEW = rotmaz(-era(ii,1))*POS_EARTH;
                POS_COAST_NEW = rotmaz(-era(ii,1))*POS_COAST;
                
                X_EARTH_NEW = reshape(POS_EARTH_NEW(1,:),row,col);
                Y_EARTH_NEW = reshape(POS_EARTH_NEW(2,:),row,col);
                Z_EARTH_NEW = reshape(POS_EARTH_NEW(3,:),row,col);
                
                X_COAST_NEW = POS_COAST_NEW(1,:)';
                Y_COAST_NEW = POS_COAST_NEW(2,:)';
                Z_COAST_NEW = POS_COAST_NEW(3,:)';
                
                IRF_AXES = rotmaz(-era(ii,1))*irf_axes;
                EFRF_AXES = rotmaz(era(ii,1))*efrf_axes;
                
                %Plot to Inertial Reference Frame
                set(h1a,'XData',X_EARTH_NEW,'YData',Y_EARTH_NEW,'ZData',Z_EARTH_NEW);
                set(h2a,'XData',X_COAST_NEW,'YData',Y_COAST_NEW,'ZData',Z_COAST_NEW);
                set(h4a,'XData',x_sat(ii,:),'YData',y_sat(ii,:),'ZData',z_sat(ii,:));
                set(h5a,'XData',x_sat(ii,:),'YData',y_sat(ii,:),'ZData',z_sat(ii,:),'UData',v_x_sat(ii,:)*1000,'VData',v_y_sat(ii,:)*1000,'WData',v_z_sat(ii,:)*1000);
                set(h6a,'XData',[0,x_sat(ii,:)],'YData',[0,y_sat(ii,:)],'ZData',[0,z_sat(ii,:)]);
                set(h8a,'XData',[0;0;0],'YData',[0;0;0],'ZData',[0;0;0],'UData',EFRF_AXES(:,1),'VData',EFRF_AXES(:,2),'WData',EFRF_AXES(:,3));set(h1a,'XData',X_EARTH_NEW,'YData',Y_EARTH_NEW,'ZData',Z_EARTH_NEW);
                set(h2a,'XData',X_COAST_NEW,'YData',Y_COAST_NEW,'ZData',Z_COAST_NEW);
                set(h4a,'XData',x_sat(ii,:),'YData',y_sat(ii,:),'ZData',z_sat(ii,:));
                set(h5a,'XData',x_sat(ii,:),'YData',y_sat(ii,:),'ZData',z_sat(ii,:),'UData',v_x_sat(ii,:)*1000,'VData',v_y_sat(ii,:)*1000,'WData',v_z_sat(ii,:)*1000);
                set(h6a,'XData',[0,x_sat(ii,:)],'YData',[0,y_sat(ii,:)],'ZData',[0,z_sat(ii,:)]);
                set(h8a,'XData',[0;0;0],'YData',[0;0;0],'ZData',[0;0;0],'UData',EFRF_AXES(:,1),'VData',EFRF_AXES(:,2),'WData',EFRF_AXES(:,3));
                
                
                %Plot to Earth Fixed Reference Frame
                set(h4b,'XData',X_sat(ii,1),'YData',Y_sat(ii,1),'ZData',Z_sat(ii,1));
                set(h5b,'XData',X_sat(ii,1),'YData',Y_sat(ii,1),'ZData',Z_sat(ii,1),'UData',V_X_sat(ii,1)*1000,'VData',V_Y_sat(ii,1)*1000,'WData',V_Z_sat(ii,1)*1000);
                set(h6b,'XData',[0 X_sat(ii,1)],'YData',[0 Y_sat(ii,1)],'ZData',[0 Z_sat(ii,1)]);
                set(h7b,'XData',[0;0;0],'YData',[0;0;0],'ZData',[0;0;0],'UData',IRF_AXES(:,1),'VData',IRF_AXES(:,2),'WData',IRF_AXES(:,3));
                
                %Plot to Earth Fixed Reference Frame (Ground Tracks)
                set(h4c,'XData',lamda_sat(ii,1),'YData',phi_sat(ii,1),'ZData',1);
                
                drawnow limitrate
                
            end
            
            enableAll(app);
            app.PauseButton.Enable = 'off';
            app.StopButton.Enable = 'off';
            
        end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Move GUI to the center of screen
            movegui(app.orbitWindowUIFigure,"center");
            % Setup Auto mode with SSS-1 data
            fname = 'SSS-1_TLE.txt';
            tle = read_tle(fname);
            kepel = tle_kepel(tle);
            updateKepel(app,kepel);
            disableFields(app);
        end

        % Image clicked function: GoBack
        function GoBackImageClicked(app, event)
            delete(app)
            main_window            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
                
            %% ---- Simulate Orbit with chosen parameters ---- %%
            
            %% Setup
            
            % Global variables
            global POS_EARTH POS_COAST X_EARTH Y_EARTH Z_EARTH  X_COAST Y_COAST Z_COAST POS_t LON LAT TOPOGRAPHY row col row2 col2 long_corr lat_corr irf_axes efrf_axes
            global GM period_Earth omega_Earth O a e w W i M theta_z T_num T t era pause_resume stop
            global h1a h2a h3a h4a h5a h6a h7a h8a h9a h1b h2b h3b h4b h5b h6b h7b h8b h9b h1c h2c h3c h4c h9c
            global earth_color coastline_color satellite_color orbit_color velocity_color radial_color irf_color efrf_color
            global LONGITUDE_t LATITUDE_t X_t Y_t Z_t day_night_map
            global x_sat y_sat z_sat v_x_sat v_y_sat v_z_sat X_sat Y_sat Z_sat V_X_sat V_Y_sat V_Z_sat phi_sat phi_sat_corr lamda_sat lamda_sat_corr
            
            pause_resume = 0;
            stop = 0;
            
            % Load Earth points and coastline data
            load EARTH_2.mat
            load COAST_3D.mat
            load('coast.mat');
            
            % Initialize colors
            earth_color = 'b';
            coastline_color = 'k';
            satellite_color = 'w';
            orbit_color  = 'r';
            velocity_color = 'k';
            radial_color = 'w';
            irf_color = 'g';
            efrf_color = 'b';
            
            % Constants
            GM = 3.98199e+14;           
            period_Earth = (23 + 56/60 + 4.0910/3600)*3600; % seconds
            omega_Earth = 2*pi/period_Earth; % Earth's angular velocity (rad/s) 
            O = [0             , -omega_Earth  , 0 ;
                 omega_Earth   , 0             , 0 ;
                 0             , 0             , 0]; 
            
            % Initialize day-night map
            [LONGITUDE_t,LATITUDE_t] = meshgrid(0:2:360,-90:2:90);
            [X_t,Y_t,Z_t] = f_geo2cart(LATITUDE_t,LONGITUDE_t,10000,'GRS 1980');
            [row2,col2] = size(X_t);
            
            x_t = X_t(:);
            y_t = Y_t(:);
            z_t = Z_t(:);
            
            POS_t = [x_t';y_t';z_t'];
            day_night_map = NaN(size(LONGITUDE_t));
            
            % Process the Earth points and coastline data
            [row,col] = size(X_EARTH);
            
            x_earth = X_EARTH(:);
            y_earth = Y_EARTH(:);
            z_earth = Z_EARTH(:);
            
            POS_EARTH = [x_earth';y_earth';z_earth'];
            POS_COAST = [X_COAST';Y_COAST';Z_COAST'];
            
            long(long < 0) = long(long < 0) + 360;
            
            %Fix east-west lines bug
            [lat_corr,long_corr] = f_fix_lines(lat,long,200);
            
            % Earth's rotation angle and number of periods for simulation
            config = getValuesConfig(app);
            
            % Get Kepel
            kepel = getValuesKepel(app);
            
            % Definition of each keplerian element
            a = kepel(1,1); % Semi-major axis
            e = kepel(1,2); % Eccentricity
            i = kepel(1,3); % Inclination
            W = kepel(1,4); % RAAN
            w = kepel(1,5); % Arg. perigee
            M = kepel(1,6); % Mean Anomaly
            
            theta_z = config(1,1)*pi/180;
            T_num = config(1,2);
            T = 2*pi*sqrt(a^3/GM)/period_Earth;
            t = (0:T/360:T_num*T+T/360)';
            era = theta_z + omega_Earth*t*period_Earth;
            
            % Get inertial state vector from the keplerian elements
            [x_sat,y_sat,z_sat,v_x_sat,v_y_sat,v_z_sat] = f_kepl2svec(GM,a,e,w,W,i,M,0,t);
            
            % Define IRF and EFRF axes 
            irf_axes  = [10000000,        0,        0 ;
                         0, 10000000,        0 ;
                         0,        0, 10000000];
            efrf_axes = [10000000,        0,        0 ;
                         0, 10000000,        0 ;
                         0,        0, 10000000];                                                  
            
            % Initialize variables
            X_sat = zeros(size(x_sat));
            Y_sat = zeros(size(y_sat));
            Z_sat = zeros(size(z_sat));
            V_X_sat = zeros(size(v_x_sat));
            V_Y_sat = zeros(size(v_y_sat));
            V_Z_sat = zeros(size(v_z_sat));
            
            %% Analytical orbit propagation and plots
            
            for ii = 1:size(x_sat,1)
            
                pos_vec = [x_sat(ii,1);y_sat(ii,1);z_sat(ii,1)];
                vel_vec = [v_x_sat(ii,1);v_y_sat(ii,1);v_z_sat(ii,1)];
            
                POS_VEC = rotmaz(era(ii,1))*pos_vec;
                VEL_VEC = rotmaz(era(ii,1))*vel_vec - O*POS_VEC;
            
                X_sat(ii,1) = POS_VEC(1,1);
                Y_sat(ii,1) = POS_VEC(2,1);
                Z_sat(ii,1) = POS_VEC(3,1);
            
                V_X_sat(ii,1) = VEL_VEC(1,1);
                V_Y_sat(ii,1) = VEL_VEC(2,1);
                V_Z_sat(ii,1) = VEL_VEC(3,1);
            
            end
            
            POS_EARTH_NEW = rotmaz(-theta_z)*POS_EARTH;
            POS_COAST_NEW = rotmaz(-theta_z)*POS_COAST;
            
            X_EARTH_NEW = reshape(POS_EARTH_NEW(1,:),row,col);
            Y_EARTH_NEW = reshape(POS_EARTH_NEW(2,:),row,col);
            Z_EARTH_NEW = reshape(POS_EARTH_NEW(3,:),row,col);
            
            X_COAST_NEW = POS_COAST_NEW(1,:)';
            Y_COAST_NEW = POS_COAST_NEW(2,:)';
            Z_COAST_NEW = POS_COAST_NEW(3,:)';
            
            IRF_AXES = rotmaz(-theta_z)*irf_axes;
            EFRF_AXES = rotmaz(theta_z)*efrf_axes;
            
            %Earth Fixed Reference Frame (Map Projection)
            [phi_sat,lamda_sat,~] = f_cart2geo(X_sat,Y_sat,Z_sat,'GRS 1980');
            
            %Fix east-west lines bug
            [phi_sat_corr,lamda_sat_corr] = f_fix_lines(phi_sat,lamda_sat,200);
            
            %% ------ Plot to Inertial Reference Frame ------- %%         
            
            %Plot Earth
            h1a = surf(app.InertialUIAxes,X_EARTH_NEW,Y_EARTH_NEW,Z_EARTH_NEW,TOPOGRAPHY);
            [cmap,~] = demcmap([min(min(TOPOGRAPHY)) max(max(TOPOGRAPHY))]);
            colormap(app.InertialUIAxes, cmap);
            shading(app.InertialUIAxes,'interp');
            hold(app.InertialUIAxes, 'on');
            
            %Plot day night map
            h9a = surf(app.InertialUIAxes,X_t,Y_t,Z_t,day_night_map);
            shading(app.InertialUIAxes,'interp');
            set(h9a,'Visible','off');
            alpha(h9a,0.5);
            
            %Plot coastline
            h2a = plot3(app.InertialUIAxes,X_COAST_NEW,Y_COAST_NEW,Z_COAST_NEW,'Color',coastline_color);
            
            %Plot satellite orbit
            h3a = plot3(app.InertialUIAxes,x_sat(1:362),y_sat(1:362),z_sat(1:362),'Color',orbit_color,'Linewidth',2);

            %Plot satellite
            h4a = scatter3(app.InertialUIAxes,x_sat(1,1),y_sat(1,1),z_sat(1,1),100,'filled','MarkerEdgeColor',satellite_color,'MarkerFaceColor',satellite_color);
            
            %Plot satellite velocity
            h5a = quiver3(app.InertialUIAxes,x_sat(1,1),y_sat(1,1),z_sat(1,1),v_x_sat(1,1)*1000,v_y_sat(1,1)*1000,v_z_sat(1,1)*1000,'Color',velocity_color,'Linewidth',2);
            
             %Plot satellite radial distance
            h6a = plot3(app.InertialUIAxes,[0 x_sat(1,1)],[0 y_sat(1,1)],[0 z_sat(1,1)],'Color',radial_color);
            
            %Plot Inertial Reference Frame axes
            h7a = quiver3(app.InertialUIAxes,[0;0;0],[0;0;0],[0;0;0],irf_axes(:,1),irf_axes(:,2),irf_axes(:,3),'Color',irf_color,'Linewidth',2,'ShowArrowHead','off');
            
            %Plot Earth Fixed Reference Frame axes
            h8a = quiver3(app.InertialUIAxes,[0;0;0],[0;0;0],[0;0;0],EFRF_AXES(:,1),EFRF_AXES(:,2),EFRF_AXES(:,3),'Color',efrf_color,'Linewidth',2,'ShowArrowHead','off');

            app.InertialUIAxes.View = [-24,48];
            axis(app.InertialUIAxes, 'equal');
            axis(app.InertialUIAxes, 'off');
            app.InertialUIAxes.XGrid = 'off';
            app.InertialUIAxes.YGrid = 'off';
            app.InertialUIAxes.ZGrid = 'off';
            app.InertialUIAxes.Box = 'off';
            rotate3d(app.InertialUIAxes, 'on');
            hold(app.InertialUIAxes, 'off');
            
            
            %% ------ Plot Earth Fixed Frame ------- %%
                        
            % Plot Earth
            h1b = surf(app.ECIUIAxes,X_EARTH,Y_EARTH,Z_EARTH,TOPOGRAPHY);
            colormap(app.ECIUIAxes, cmap);
            shading(app.ECIUIAxes,'interp');
            hold(app.ECIUIAxes,'on');
            
            %Plot day night map
            h9b = surf(app.ECIUIAxes,X_t,Y_t,Z_t,day_night_map);
            shading(app.ECIUIAxes,'interp');
            set(h9b,'Visible','off');
            alpha(h9b,0.5);
            
            % Plot coastline
            h2b = plot3(app.ECIUIAxes,X_COAST,Y_COAST,Z_COAST,'Color',coastline_color);
            
            % Plot satelite orbit
            h3b = plot3(app.ECIUIAxes,X_sat,Y_sat,Z_sat,'Color',orbit_color,'Linewidth',2);
            
            % Plot satellite
            h4b = scatter3(app.ECIUIAxes,x_sat(1,1),Y_sat(1,1),Z_sat(1,1),100,'filled','MarkerEdgeColor',satellite_color,'MarkerFaceColor',satellite_color);            
            
            % Plot satellite velocity
            h5b = quiver3(app.ECIUIAxes,X_sat(1,1),Y_sat(1,1),Z_sat(1,1),V_X_sat(1,1)*1000,V_Y_sat(1,1)*1000,V_Z_sat(1,1)*1000,'Color',velocity_color,'Linewidth',2);
            
            % Plot satellite radial distance
            h6b = plot3(app.ECIUIAxes,[0 X_sat(1,1)],[0 Y_sat(1,1)],[0 Z_sat(1,1)],'Color',radial_color);
            
            % Plot Inertial Reference Frame axes
            h7b = quiver3(app.ECIUIAxes,[0;0;0],[0;0;0],[0;0;0],IRF_AXES(:,1),IRF_AXES(:,2),IRF_AXES(:,3),'Color',irf_color,'Linewidth',2,'ShowArrowHead','off');
            
            % Plot Earth Fixed Reference Frame axes
            h8b = quiver3(app.ECIUIAxes,[0;0;0],[0;0;0],[0;0;0],efrf_axes(:,1),efrf_axes(:,2),efrf_axes(:,3),'Color',efrf_color,'Linewidth',2,'ShowArrowHead','off');
            
            app.ECIUIAxes.View = [-24,48];
            axis(app.ECIUIAxes,'equal');
            axis(app.ECIUIAxes,'off');
            app.ECIUIAxes.XGrid = 'off';
            app.ECIUIAxes.YGrid = 'off';
            app.ECIUIAxes.ZGrid = 'off';
            app.ECIUIAxes.Box = 'off';
            rotate3d(app.ECIUIAxes, 'on');
            hold(app.ECIUIAxes, 'off');
            
            %% ------ Plot Ground Track ------- %%
     
            % Plot Earth
            h1c = pcolor(app.GroundUIAxes,LON,LAT,TOPOGRAPHY);
            colormap(app.GroundUIAxes, cmap);
            shading(app.GroundUIAxes, 'interp');           
            hold(app.GroundUIAxes, 'on');
            
            %Plot day night map
            h9c = pcolor(app.GroundUIAxes,LONGITUDE_t,LATITUDE_t,day_night_map);            
            shading(app.GroundUIAxes, 'interp');
            set(h9c,'Visible','off');
            alpha(h9c,0.5);
            
            %Plot coastline
            h2c = plot(app.GroundUIAxes,long_corr,lat_corr,'Color',coastline_color);
            
            %Plot satellite orbit
            h3c = plot(app.GroundUIAxes,lamda_sat_corr,phi_sat_corr,'Color',orbit_color,'Linewidth',2);
            
            %Plot satellite
            h4c = scatter(app.GroundUIAxes,lamda_sat(1,1),phi_sat(1,1),100,'filled','MarkerEdgeColor',satellite_color,'MarkerFaceColor',satellite_color);
            set(h4c,'ZData',1);
            
            axis(app.GroundUIAxes,'equal');
            axis(app.GroundUIAxes,'off');
            app.GroundUIAxes.XGrid = 'off';
            app.GroundUIAxes.YGrid = 'off';
            app.GroundUIAxes.ZGrid = 'off';                    
            rotate3d(app.GroundUIAxes, 'on');
            hold(app.GroundUIAxes, 'off');
            
            
            %% ----------- Animation ----------- %%             
            value = app.AnimateCheckBox.Value;
            if (value == 1)
                animateSimulatedOrbit(app);
            end
            
            
        end

        % Value changed function: AutoCheckBox
        function AutoCheckBoxValueChanged(app, event)
            check = app.AutoCheckBox.Value;
            if (check == 1) % Auto mode
                app.SatelliteTLEDropDown.Value = 'SSS-1';
                fname = 'SSS-1_TLE.txt';
                tle = read_tle(fname);
                kepel = tle_kepel(tle);
                updateKepel(app,kepel);
                disableFields(app);
            else % Manual mode: student chooses another satellite or changes specific values
                enableFields(app);
                app.SatelliteTLEDropDown.Value = '-';
            end
        end

        % Value changed function: SatelliteTLEDropDown
        function SatelliteTLEDropDownValueChanged(app, event)
            
            value = app.SatelliteTLEDropDown.Value;
            if (value == "SSS-1")
                fname = 'SSS-1_TLE.txt';
            elseif (value == "ISS")
                fname = 'ISSTLE.txt';
            elseif (value == "COSMOS 2024")
                fname = 'COSMOS2024_TLE.txt';
            elseif (value == "FM-6")
                fname = 'FM-6_TLE.txt';
            end
            tle = read_tle(fname);
            kepel = tle_kepel(tle);
            updateKepel(app,kepel);
            
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            
            global pause_resume
            
            if (pause_resume == 1)
                pause_resume = 0;
                app.PauseButton.Text = 'Pause';
            elseif (pause_resume == 0)
                pause_resume = 1;
                app.PauseButton.Text = 'Resume';
            end
            
        end

        % Value changed function: AnimateCheckBox
        function AnimateCheckBoxValueChanged(app, event)
            
            value = app.AnimateCheckBox.Value;
            if (value == 0)
                app.PlotButton.Text = 'Plot';            
            elseif (value == 1)
                app.PlotButton.Text = 'Start';
            end
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            global stop
            stop = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.orbitWindowUIFigure = uifigure('Visible', 'off');
            app.orbitWindowUIFigure.Name = 'orbitWindow';
            app.OrbitSimulatorLabel = uilabel(app.orbitWindowUIFigure);
            app.OrbitSimulatorLabel.Text = 'Orbit Simulator';
            app.GoBack = uiimage(app.orbitWindowUIFigure);
            app.GoBack.ImageClickedFcn = createCallbackFcn(app, @GoBackImageClicked, true);
            app.GoBack.ImageSource = 'arrow icon.png';
            app.SelectKeplerianElementsPanel = uipanel(app.orbitWindowUIFigure);
            app.SelectKeplerianElementsPanel.Title = 'Select Keplerian Elements';
            app.SemimajoraxismEditFieldLabel = uilabel(app.SelectKeplerianElementsPanel);
            app.SemimajoraxismEditFieldLabel.Text = 'Semi-major axis (m):';
            app.SemiMajorAxisEditField = uieditfield(app.SelectKeplerianElementsPanel, 'numeric');
            app.SemiMajorAxisEditField.Limits = [6371000 100000000];
            app.SemiMajorAxisEditField.Value = 6371000;
            app.EccentricityLabel = uilabel(app.SelectKeplerianElementsPanel);
            app.EccentricityLabel.Text = 'Eccentricity:';
            app.EccentricityEditField = uieditfield(app.SelectKeplerianElementsPanel, 'numeric');
            app.EccentricityEditField.Limits = [0 1];
            app.ArgumentofperigeedegEditFieldLabel = uilabel(app.SelectKeplerianElementsPanel);
            app.ArgumentofperigeedegEditFieldLabel.Text = 'Argument of perigee (deg):';
            app.ArgumentOfPerigeeEditField = uieditfield(app.SelectKeplerianElementsPanel, 'numeric');
            app.ArgumentOfPerigeeEditField.Limits = [0 360];
            app.RightascensionofascendingnodedegEditFieldLabel = uilabel(app.SelectKeplerianElementsPanel);
            app.RightascensionofascendingnodedegEditFieldLabel.Text = 'Right ascension of ascending node (deg):';
            app.RightAscensionEditField = uieditfield(app.SelectKeplerianElementsPanel, 'numeric');
            app.RightAscensionEditField.Limits = [0 360];
            app.InclinationEditFieldLabel = uilabel(app.SelectKeplerianElementsPanel);
            app.InclinationEditFieldLabel.Text = 'Inclination:';
            app.InclinationEditField = uieditfield(app.SelectKeplerianElementsPanel, 'numeric');
            app.InclinationEditField.Limits = [0 180];
            app.MeananomalydegEditFieldLabel = uilabel(app.SelectKeplerianElementsPanel);
            app.MeananomalydegEditFieldLabel.Text = 'Mean anomaly (deg):';
            app.MeanAnomalyEditField = uieditfield(app.SelectKeplerianElementsPanel, 'numeric');
            app.MeanAnomalyEditField.Limits = [0 360];
            app.SatelliteTLEDropDownLabel = uilabel(app.SelectKeplerianElementsPanel);
            app.SatelliteTLEDropDownLabel.Text = 'Satellite (TLE)';
            app.SatelliteTLEDropDown = uidropdown(app.SelectKeplerianElementsPanel);
            app.SatelliteTLEDropDown.Items = {'-', 'SSS-1', 'ISS', 'COSMOS 2024', 'FM-6'};
            app.SatelliteTLEDropDown.ValueChangedFcn = createCallbackFcn(app, @SatelliteTLEDropDownValueChanged, true);
            app.SatelliteTLEDropDown.Value = 'SSS-1';
            app.AutoCheckBox = uicheckbox(app.SelectKeplerianElementsPanel);
            app.AutoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoCheckBoxValueChanged, true);
            app.AutoCheckBox.Text = 'Auto';
            app.AutoCheckBox.Value = true;
            app.OtherconfigPanel = uipanel(app.orbitWindowUIFigure);
            app.OtherconfigPanel.Title = 'Other config.';
            app.EarthrotationangledegEditFieldLabel = uilabel(app.OtherconfigPanel);
            app.EarthrotationangledegEditFieldLabel.Text = 'Earth rotation angle (deg):';
            app.EarthrotationangledegEditField = uieditfield(app.OtherconfigPanel, 'numeric');
            app.EarthrotationangledegEditField.Limits = [0 360];
            app.EarthrotationangledegEditField.Value = 10;
            app.NofperiodsEditFieldLabel = uilabel(app.OtherconfigPanel);
            app.NofperiodsEditFieldLabel.Text = 'Nº of periods:';
            app.NofperiodsEditField = uieditfield(app.OtherconfigPanel, 'numeric');
            app.NofperiodsEditField.Limits = [1 100];
            app.NofperiodsEditField.Value = 1;
            app.InertialReferenceFramePanel = uipanel(app.orbitWindowUIFigure);
            app.InertialReferenceFramePanel.Title = 'Inertial Reference Frame';
            app.InertialUIAxes = uiaxes(app.InertialReferenceFramePanel);
            title(app.InertialUIAxes, '')
            xlabel(app.InertialUIAxes, '')
            ylabel(app.InertialUIAxes, '')
            app.ECIGroundtrackPanel = uipanel(app.orbitWindowUIFigure);
            app.ECIGroundtrackPanel.Title = 'ECI Groundtrack';
            app.GroundUIAxes = uiaxes(app.ECIGroundtrackPanel);
            title(app.GroundUIAxes, '')
            xlabel(app.GroundUIAxes, '')
            ylabel(app.GroundUIAxes, '')
            app.EarthCenteredInertialFramePanel = uipanel(app.orbitWindowUIFigure);
            app.EarthCenteredInertialFramePanel.Title = 'Earth Centered Inertial Frame';
            app.ECIUIAxes = uiaxes(app.EarthCenteredInertialFramePanel);
            title(app.ECIUIAxes, '')
            xlabel(app.ECIUIAxes, '')
            ylabel(app.ECIUIAxes, '')
            app.PlotButton = uibutton(app.orbitWindowUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Tooltip = {''};
            app.PlotButton.Text = 'Plot';
            app.AnimationPanel = uipanel(app.orbitWindowUIFigure);
            app.AnimationPanel.Title = 'Animation';
            app.StopButton = uibutton(app.AnimationPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.AnimateCheckBox = uicheckbox(app.AnimationPanel);
            app.AnimateCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnimateCheckBoxValueChanged, true);
            app.AnimateCheckBox.Text = 'Animate';
            app.PauseButton = uibutton(app.AnimationPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Enable = 'off';
            app.PauseButton.Text = 'Pause';
            % Show the figure after all components are created
            app.orbitWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = orbit_sim_window

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.orbitWindowUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.orbitWindowUIFigure)
        end
    end
end